classdef PoseVisualization < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                   matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        RotationMatrixPanel        matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        EditField_nx               matlab.ui.control.NumericEditField
        EditField_ox               matlab.ui.control.NumericEditField
        EditField_ax               matlab.ui.control.NumericEditField
        EditField_ny               matlab.ui.control.NumericEditField
        EditField_oy               matlab.ui.control.NumericEditField
        EditField_ay               matlab.ui.control.NumericEditField
        EditField_nz               matlab.ui.control.NumericEditField
        EditField_oz               matlab.ui.control.NumericEditField
        EditField_az               matlab.ui.control.NumericEditField
        ApplyButton_matrix         matlab.ui.control.Button
        step1DropDownLabel         matlab.ui.control.Label
        step1DropDown              matlab.ui.control.DropDown
        EditField_step1            matlab.ui.control.NumericEditField
        step2DropDownLabel         matlab.ui.control.Label
        step2DropDown              matlab.ui.control.DropDown
        step3DropDownLabel         matlab.ui.control.Label
        step3DropDown              matlab.ui.control.DropDown
        EditField_step2            matlab.ui.control.NumericEditField
        EditField_step3            matlab.ui.control.NumericEditField
        Button_convert_to_matrix   matlab.ui.control.Button
        Label_step1_unit           matlab.ui.control.Label
        Label_step2_unit           matlab.ui.control.Label
        Label_step3_unit           matlab.ui.control.Label
        EulerAnglesZYXPanel        matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        yawLabel                   matlab.ui.control.Label
        yawEditField               matlab.ui.control.NumericEditField
        pitchLabel                 matlab.ui.control.Label
        pitchEditField             matlab.ui.control.NumericEditField
        rollLabel                  matlab.ui.control.Label
        rollEditField              matlab.ui.control.NumericEditField
        ApplyButton_euler          matlab.ui.control.Button
        Label_yaw_unit             matlab.ui.control.Label
        Label_pitch_unit           matlab.ui.control.Label
        Label_roll_unit            matlab.ui.control.Label
        RotationVectorPanel        matlab.ui.container.Panel
        GridLayout3_2              matlab.ui.container.GridLayout
        RXEditFieldLabel           matlab.ui.control.Label
        RXEditField                matlab.ui.control.NumericEditField
        RYEditFieldLabel           matlab.ui.control.Label
        RYEditField                matlab.ui.control.NumericEditField
        RZEditFieldLabel           matlab.ui.control.Label
        RZEditField                matlab.ui.control.NumericEditField
        ApplyButton_vector         matlab.ui.control.Button
        UnitQuaternionPanel        matlab.ui.container.Panel
        GridLayout4_2              matlab.ui.container.GridLayout
        q0EditFieldLabel           matlab.ui.control.Label
        q0EditField                matlab.ui.control.NumericEditField
        q1EditFieldLabel           matlab.ui.control.Label
        q1EditField                matlab.ui.control.NumericEditField
        q2EditFieldLabel           matlab.ui.control.Label
        q2EditField                matlab.ui.control.NumericEditField
        q3EditFieldLabel           matlab.ui.control.Label
        q3EditField                matlab.ui.control.NumericEditField
        ApplyButton_quaternion     matlab.ui.control.Button
        AxisAnglePanel             matlab.ui.container.Panel
        GridLayout4                matlab.ui.container.GridLayout
        AngleLabel                 matlab.ui.control.Label
        AngleEditField             matlab.ui.control.NumericEditField
        kxEditFieldLabel           matlab.ui.control.Label
        kxEditField                matlab.ui.control.NumericEditField
        kyEditFieldLabel           matlab.ui.control.Label
        kyEditField                matlab.ui.control.NumericEditField
        kzEditFieldLabel           matlab.ui.control.Label
        kzEditField                matlab.ui.control.NumericEditField
        ApplyButton_axis           matlab.ui.control.Button
        Label_axis_angle_unit      matlab.ui.control.Label
        Switch                     matlab.ui.control.Switch
        Panel                      matlab.ui.container.Panel
        GridLayout5                matlab.ui.container.GridLayout
        SelectPoseLabel            matlab.ui.control.Label
        SelectPoseDropDown         matlab.ui.control.DropDown
        Button_addPose             matlab.ui.control.Button
        Button_delPose             matlab.ui.control.Button
        ApplyButton_runSelectPose  matlab.ui.control.Button
        EditField_inputPoseName    matlab.ui.control.EditField
        UIAxes                     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        isRad = false % check system of angular unit
        pose = eye(4); % pose matrix
        poseSet = {} % record all pose
    end
    
    methods (Access = private)
        
        function drawArrow(app, from, to, varargin)
            % Check the number of input parameters
            narginchk(2, inf);
            
            % Set default parameters or specify parameters
            opt = argparse(app, varargin);
            
            T = transform(app, from, to);
            r = opt.arrow_shape : -0.01 : 0;
            [X, Y, Z] = cylinder(r, 30);
            X = X * opt.arrow_size;
            Y = Y * opt.arrow_size;
            Z = (Z-1) * opt.arrow_size;
            [L, W] = size(Z);
            for i = 1:L
                for j = 1:W
                    temp = T * [X(i,j) Y(i,j) Z(i,j) 1]';
                    X(i,j) = temp(1);
                    Y(i,j) = temp(2);
                    Z(i,j) = temp(3);
                end
            end
            surf(app.UIAxes, X, Y, Z, 'FaceColor', opt.arrow_color, 'EdgeColor', 'none');
            plot3(app.UIAxes, [from(1) to(1)], [from(2) to(2)], [from(3) to(3)], opt.handle_color, 'LineWidth', opt.handle_thickness);
        end
        
        function T = transform(~, from, to)
            % Check whether the input is a column vector(3 degree).
            if ~iscolumn(from)
                from = from';
            end
            if ~iscolumn(to)
                to = to';
            end
            % 旋转前后的矢量
            P1 = [0 0 1]';
            P2 = to - from;
            
            % 轴角
            % 两向量夹角
            q = acos(dot(P1, P2) / (norm(P1) * norm(P2)));
            if q == 0
                T = [eye(3), to; 0 0 0 1];
                return;
            elseif q == pi
                R = [1 0 0; 0 -1 0; 0 0 -1];
                T = [R, to; 0 0 0 1];
                return;
            end
            % 旋转轴
            e = [P1(2)*P2(3) - P1(3)*P2(2);
                P1(3)*P2(1) - P1(1)*P2(3);
                P1(1)*P2(2) - P1(2)*P2(1)];
            e = e/norm(e);
            R = [e(1)^2*(1-cos(q))+cos(q), e(1)*e(2)*(1-cos(q))-e(3)*sin(q), e(1)*e(3)*(1-cos(q))+e(2)*sin(q);
                e(1)*e(2)*(1-cos(q))+e(3)*sin(q), e(2)^2*(1-cos(q))+cos(q), e(2)*e(3)*(1-cos(q))-e(1)*sin(q);
                e(1)*e(3)*(1-cos(q))-e(2)*sin(q), e(2)*e(3)*(1-cos(q))+e(1)*sin(q), e(3)^2*(1-cos(q))+cos(q)];
            
            T = [R, to; 0 0 0 1];
        end
        
        function opt = argparse(~, argv)
            % Set the ratio of arrow radius to height. (r:h)
            parameterNameIndex = strcmp('arrow_shape', argv);
            if ~any(parameterNameIndex)
                opt.arrow_shape = 0.2;
            else
                parameterValueIndex = find(parameterNameIndex) + 1;
                if isnumeric(argv{parameterValueIndex})
                    opt.arrow_shape = argv{parameterValueIndex};
                else
                    error('Arrow shape scale parameter must be numeric');
                end
            end
            
            % Set the size of arrow.
            parameterNameIndex = strcmp('arrow_size', argv);
            if ~any(parameterNameIndex)
                opt.arrow_size = 0.2;
            else
                parameterValueIndex = find(parameterNameIndex) + 1;
                if isnumeric(argv{parameterValueIndex})
                    opt.arrow_size = argv{parameterValueIndex};
                else
                    error('Arrow size parameter must be numeric');
                end
            end
            
            % Set the color of arrow.
            parameterNameIndex = strcmp('arrow_color', argv);
            if ~any(parameterNameIndex)
                opt.arrow_color = 'r';
            else
                parameterValueIndex = find(parameterNameIndex) + 1;
                if ischar(argv{parameterValueIndex})
                    opt.arrow_color = argv{parameterValueIndex};
                else
                    error('Arrow color parameter must be string');
                end
            end
            
            % Set the color of arrow handle.
            parameterNameIndex = strcmp('handle_color', argv);
            if ~any(parameterNameIndex)
                opt.handle_color = 'r';
            else
                parameterValueIndex = find(parameterNameIndex) + 1;
                if ischar(argv{parameterValueIndex})
                    opt.handle_color = argv{parameterValueIndex};
                else
                    error('Arrow handle color parameter must be string');
                end
            end
            
            % Set the thickness of arrow handle.
            parameterNameIndex = strcmp('handle_thickness', argv);
            if ~any(parameterNameIndex)
                opt.handle_thickness = 1;
            else
                parameterValueIndex = find(parameterNameIndex) + 1;
                if isnumeric(argv{parameterValueIndex})
                    opt.handle_thickness = argv{parameterValueIndex};
                else
                    error('Arrow handle thickness parameter must be numeric');
                end
            end
        end
        
        function drawAxes(app, transform, ratio)
            axis_x = transform(1:3, 1)';
            axis_y = transform(1:3, 2)';
            axis_z = transform(1:3, 3)';
            loc_ori = transform(1:3, 4)';
            
            px = loc_ori+axis_x * ratio;
            py = loc_ori+axis_y * ratio;
            pz = loc_ori+axis_z * ratio;
            plot3(app.UIAxes, [loc_ori(1) px(1)], [loc_ori(2) px(2)], [loc_ori(3) px(3)], 'Color', 'r', 'LineWidth', 3);
            plot3(app.UIAxes, [loc_ori(1) py(1)], [loc_ori(2) py(2)], [loc_ori(3) py(3)], 'Color', 'g', 'LineWidth', 3);
            plot3(app.UIAxes, [loc_ori(1) pz(1)], [loc_ori(2) pz(2)], [loc_ori(3) pz(3)], 'Color', 'b', 'LineWidth', 3);
            %                 arrow3(loc_ori, px, 'r');
            %                 arrow3(loc_ori, py, 'g');
            %                 arrow3(loc_ori, pz, 'b');
            %             drawArrow(app, loc_ori, px, ...
            %                 'arrow_color', 'r', ...
            %                 'handle_color', 'r', ...
            %                 'handle_thickness', 1.8, ...
            %                 'arrow_shape', 0.18, ...
            %                 'arrow_size', ratio*0.25);
            %             drawArrow(app, loc_ori, py, ...
            %                 'arrow_color', 'g', ...
            %                 'handle_color', 'g', ...
            %                 'handle_thickness', 1.8, ...
            %                 'arrow_shape', 0.18, ...
            %                 'arrow_size', ratio*0.25);
            %             drawArrow(app, loc_ori, pz, ...
            %                 'arrow_color', 'b', ...
            %                 'handle_color', 'b', ...
            %                 'handle_thickness', 1.8, ...
            %                 'arrow_shape', 0.18, ...
            %                 'arrow_size', ratio*0.25);
            scatter3(app.UIAxes, loc_ori(1), loc_ori(2), loc_ori(3), ...
                'MarkerEdgeColor','k',...
                'MarkerFaceColor',[0 .75 .75]);
            text(app.UIAxes, px(1), px(2), px(3), 'x', 'FontSize', 14, 'FontWeight', "bold");
            text(app.UIAxes, py(1), py(2), py(3), 'y', 'FontSize', 14, 'FontWeight', "bold");
            text(app.UIAxes, pz(1), pz(2), pz(3), 'z', 'FontSize', 14, 'FontWeight', "bold");
            
        end
        
        function drawObject(app, transform)
            cla(app.UIAxes);
            axesInit(app);
            
            % cube corner
            cube_points_start = [1,1,1;1,-1,1;-1,-1,1;-1,1,1;1,1,-1;1,-1,-1;-1,-1,-1;-1,1,-1];
            cube_points_target = transform(1:3,1:3) * cube_points_start';
            drawCube(app, cube_points_target');
            drawAxes(app, transform, 6);
        end
        
        function drawCube(app, cube_points)
            % cube corner
            patch(app.UIAxes, 'Faces', [1,2,3,4], 'Vertices', cube_points, 'FaceColor', 'blue', 'FaceAlpha', 0.8);
            patch(app.UIAxes, 'Faces', [5,6,7,8], 'Vertices', cube_points, 'FaceColor', 'blue', 'FaceAlpha', 1);
            patch(app.UIAxes, 'Faces', [1,4,8,5], 'Vertices', cube_points, 'FaceColor', 'green', 'FaceAlpha', 0.8);
            patch(app.UIAxes, 'Faces', [2,3,7,6], 'Vertices', cube_points, 'FaceColor', 'green', 'FaceAlpha', 1);
            patch(app.UIAxes, 'Faces', [1,5,6,2], 'Vertices', cube_points, 'FaceColor', 'red', 'FaceAlpha', 0.8);
            patch(app.UIAxes, 'Faces', [4,3,7,8], 'Vertices', cube_points, 'FaceColor', 'red', 'FaceAlpha', 1);
            
        end
        
        function axesInit(app)
            % init figure
            app.UIFigure.Color =  'white';
            app.UIFigure.NumberTitle = 'off';
            app.UIFigure.Name = 'PoseVisualization';
            app.UIFigure.MenuBar = 'none';
            set(app.UIFigure, 'Renderer', 'opengl');
            % init axes
            app.UIAxes.View = [60, 30];
            app.UIAxes.Projection = 'perspective';
            app.UIAxes.XLim = [-10, 10];
            app.UIAxes.YLim = [-10, 10];
            app.UIAxes.ZLim = [-10, 10];
            title(app.UIAxes, '');
            axis(app.UIAxes, 'off');
            grid(app.UIAxes, 'off');
            daspect(app.UIAxes, [1 1 1]);
            hold(app.UIAxes, 'on');
            % plot horizontal plane
            mesh_density = 11;
            x = linspace(-10, 10, mesh_density);
            y = linspace(-10, 10, mesh_density);
            [X, Y] = meshgrid(x, y);
            X(:, (mesh_density+1)/2) = [];
            Y(:, (mesh_density+1)/2) = [];
            X((mesh_density+1)/2, :) = [];
            Y((mesh_density+1)/2, :) = [];
            Z = zeros(mesh_density-1);
            surf(app.UIAxes, X, Y, Z, 'FaceColor', 'none', 'EdgeColor', '#e5e5e5', 'EdgeAlpha', 0.8);
            % plot axes
            line(app.UIAxes, [-10, 0], [0, 0], [0, 0], 'Color', '#C8C8C8', 'LineWidth', 1.2);
            line(app.UIAxes, [0, 0], [-10, 0], [0, 0], 'Color', '#C8C8C8', 'LineWidth', 1.2);
            line(app.UIAxes, [0, 10], [0, 0], [0, 0], 'Color', '#FFC8C8', 'LineWidth', 1.2);
            line(app.UIAxes, [0, 0], [0, 10], [0, 0], 'Color', '#C8FFC8', 'LineWidth', 1.2);
            line(app.UIAxes, [0, 0], [0, 0], [0, 10], 'Color', '#C8C8FF', 'LineWidth', 1.2);
        end
        
        function poseDataInit(app)
            % init system of angular unit
            app.isRad = false;
            % set value display format
            valueDisplayFormat = '%6.3g';
            app.EditField_step1.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_step2.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_step3.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_nx.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_ny.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_nz.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_ox.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_oy.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_oz.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_ax.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_ay.ValueDisplayFormat = valueDisplayFormat;
            app.EditField_az.ValueDisplayFormat = valueDisplayFormat;
            app.yawEditField.ValueDisplayFormat = valueDisplayFormat;
            app.pitchEditField.ValueDisplayFormat = valueDisplayFormat;
            app.rollEditField.ValueDisplayFormat = valueDisplayFormat;
            app.AngleEditField.ValueDisplayFormat = valueDisplayFormat;
            app.kxEditField.ValueDisplayFormat = valueDisplayFormat;
            app.kyEditField.ValueDisplayFormat = valueDisplayFormat;
            app.kzEditField.ValueDisplayFormat = valueDisplayFormat;
            app.q0EditField.ValueDisplayFormat = valueDisplayFormat;
            app.q1EditField.ValueDisplayFormat = valueDisplayFormat;
            app.q2EditField.ValueDisplayFormat = valueDisplayFormat;
            app.q3EditField.ValueDisplayFormat = valueDisplayFormat;
            % init pose
            app.pose = eye(4);
            % init input
            setRotationMatrix(app, getRotationMatrix(app));
            setEulerAnglesZYX(app, getEulerAnglesZYX(app));
            setRotationVector(app, getRotationVector(app));
            setAxisAngle(app, getAxisAngle(app));
            setUnitQuaternion(app, getUnitQuaternion(app));
            % init poseSet
            app.poseSet = {};
            app.poseSet{end+1} = app.pose;
        end
        
        function R = getRotationMatrix(app)
            R = app.pose(1:3, 1:3);
        end
        
        function euler_angles = getEulerAnglesZYX(app)
            euler_angles = mat2eulZYX(app, app.pose, 'degree');
        end
        
        function rotation_vector = getRotationVector(app)
            rotation_vector = mat2rotVec(app, app.pose);
        end
        
        function axis_angles = getAxisAngle(app)
            axis_angles = mat2axAng(app, app.pose);
        end
        
        function unit_quaternion = getUnitQuaternion(app)
            unit_quaternion = mat2unitQuat(app, app.pose);
        end
        
        function setRotationMatrix(app, rotation_matrix)
            % Avoid appear: -0
            rotation_matrix(abs(rotation_matrix)<eps) = 0;
            % display value
            app.EditField_nx.Value = rotation_matrix(1,1);
            app.EditField_ny.Value = rotation_matrix(2,1);
            app.EditField_nz.Value = rotation_matrix(3,1);
            app.EditField_ox.Value = rotation_matrix(1,2);
            app.EditField_oy.Value = rotation_matrix(2,2);
            app.EditField_oz.Value = rotation_matrix(3,2);
            app.EditField_ax.Value = rotation_matrix(1,3);
            app.EditField_ay.Value = rotation_matrix(2,3);
            app.EditField_az.Value = rotation_matrix(3,3);
        end
        
        function setEulerAnglesZYX(app, euler_angles)
            % Avoid appear: -0
            euler_angles(abs(euler_angles)<eps) = 0;
            % display value
            app.yawEditField.Value = euler_angles(1);
            app.pitchEditField.Value = euler_angles(2);
            app.rollEditField.Value = euler_angles(3);
        end
        
        function setRotationVector(app, rotation_vector)
            % Avoid appear: -0
            rotation_vector(abs(rotation_vector)<eps) = 0;
            % display value
            app.RXEditField.Value = rotation_vector(1);
            app.RYEditField.Value = rotation_vector(2);
            app.RZEditField.Value = rotation_vector(3);
        end
        
        function setAxisAngle(app, axis_angles)
            % Avoid appear: -0
            axis_angles(abs(axis_angles)<eps) = 0;
            % display value
            if app.isRad == false
                axis_angles(1) = rad2deg(axis_angles(1));
            end
            app.AngleEditField.Value = axis_angles(1);
            app.kxEditField.Value = axis_angles(2);
            app.kyEditField.Value = axis_angles(3);
            app.kzEditField.Value = axis_angles(4);
        end
        
        function setUnitQuaternion(app, unit_quaternion)
            % Avoid appear: -0
            unit_quaternion(abs(unit_quaternion)<eps) = 0;
            % display value
            app.q0EditField.Value = unit_quaternion(1);
            app.q1EditField.Value = unit_quaternion(2);
            app.q2EditField.Value = unit_quaternion(3);
            app.q3EditField.Value = unit_quaternion(4);
        end
        
        function results = checkInputValidity(app, checkItem, checkData)
            results = true;
            switch checkItem
                case 'rotation_matrix'
                    res1 = checkData' * checkData - eye(size(checkData)) < 100*eps;
                    res2 = checkData * checkData' - eye(size(checkData)) < 100*eps;
                    if ~isequal(res1, res2, ones(size(checkData)))
                        results = false;
                        uialert(app.UIFigure, 'The input rotation matrix is invalid!', 'Invalid Input');
                    end
                case 'euler_angles'
                    if app.isRad
                        checkData = rad2deg(checkData);
                    end
                    yawInRange = (checkData(1) >= -180) && (checkData(1) <= 180);
                    pitchInRange = (checkData(2) >= -90) && (checkData(2) <= 90);
                    rollInRange = (checkData(3) >= -180) && (checkData(3) <= 180);
                    if ~(yawInRange && pitchInRange && rollInRange)
                        results = false;
                        msg = sprintf('The input euler angles(ZYX) is invalid! \n - The yaw Angle ranges from -180(-pi) to 180(pi)\n - The pitch Angle ranges from -90(-pi/2) to 90(pi/2)\n - The roll Angle ranges from -180(-pi) to 180(pi)');
                        uialert(app.UIFigure, msg, 'Invalid Input');
                    end
                case 'rotation_vector'
                    
                case 'axis_angles'
                    
                case 'unit_quaternion'
                    
            end
        end
        
        function poseRun(app, mode)
            if nargin < 2
                mode = 0;
            end
            drawObject(app, app.pose);
            %             updateMotionData(app); % BUG
        end
        
        
        function switchUnitSystem(app, nowIsRad)
            if app.isRad == nowIsRad
                return;
            end
            app.isRad = nowIsRad;
            % change label
            label_unit = "";
            switch nowIsRad
                case true
                    label_unit = "rad";
                    app.EditField_step1.Value = deg2rad(app.EditField_step1.Value);
                    app.EditField_step2.Value = deg2rad(app.EditField_step2.Value);
                    app.EditField_step3.Value = deg2rad(app.EditField_step3.Value);
                    app.yawEditField.Value = deg2rad(app.yawEditField.Value);
                    app.pitchEditField.Value = deg2rad(app.pitchEditField.Value);
                    app.rollEditField.Value = deg2rad(app.rollEditField.Value);
                    app.AngleEditField.Value = deg2rad(app.AngleEditField.Value);
                case false
                    label_unit = "°";
                    app.EditField_step1.Value = rad2deg(app.EditField_step1.Value);
                    app.EditField_step2.Value = rad2deg(app.EditField_step2.Value);
                    app.EditField_step3.Value = rad2deg(app.EditField_step3.Value);
                    app.yawEditField.Value = rad2deg(app.yawEditField.Value);
                    app.pitchEditField.Value = rad2deg(app.pitchEditField.Value);
                    app.rollEditField.Value = rad2deg(app.rollEditField.Value);
                    app.AngleEditField.Value = rad2deg(app.AngleEditField.Value);
            end
            app.Label_step1_unit.Text = label_unit;
            app.Label_step2_unit.Text = label_unit;
            app.Label_step3_unit.Text = label_unit;
            app.Label_yaw_unit.Text = label_unit;
            app.Label_pitch_unit.Text = label_unit;
            app.Label_roll_unit.Text = label_unit;
            app.Label_axis_angle_unit.Text = label_unit;
        end
        
        function eulerAngles = mat2eulZYX(~, tranform_matrix, unit)
            R = tranform_matrix(1:3, 1:3);
            angleY_pitch = atan2(-R(3,1), sqrt(R(1,1)^2 + R(2,1)^2));
            if abs(angleY_pitch - pi/2) < eps
                angleY_pitch = pi/2;
                angleZ_yaw = 0;
                angleX_roll = atan2(R(1,2), R(2,2));
            elseif abs(angleY_pitch + pi/2) < eps
                angleY_pitch = -pi/2;
                angleZ_yaw = 0;
                angleX_roll = -atan2(R(1,2), R(2,2));
            else
                angleZ_yaw = atan2(R(2,1), R(1,1));
                angleX_roll = atan2(R(3,2), R(3,3));
            end
            eulerAngles = [angleZ_yaw, angleY_pitch, angleX_roll];
            if strcmp(unit, 'degree')
                eulerAngles = rad2deg(eulerAngles);
            end
        end
        
        function axisAngles = mat2axAng(~, tranform_matrix)
            temp = rotm2axang(tranform_matrix(1:3, 1:3));
            axisAngles(1) = temp(4);
            axisAngles(2:4) = temp(1:3);
            
            % 待改进
%             R = tranform_matrix(1:3, 1:3);
%             angle = acos((R(1,1) + R(2,2) + R(3,3) - 1)/2);
%             if abs(angle) < eps
%                 axisAngles = zeros(1,4);
%             elseif abs(angle - pi) < eps
%                 if (R - rotx(180)) < eps
%                     axisAngles = [pi 1 0 0];
%                 elseif (R - roty(180)) < eps
%                     axisAngles = [pi 0 1 0];
%                 elseif (R - rotz(180)) < eps
%                     axisAngles = [pi 0 0 1];
%                     % TODO:还差 8 种情况
%                 end
%             else
%                 kx = R(3,2) - R(2,3);
%                 ky = R(1,3) - R(3,1);
%                 kz = R(2,1) - R(1,2);
%                 axisAngles = [angle kx ky kz];
%                 axisAngles(2:4) = axisAngles(2:4)/sqrt(sum(axisAngles(2:4).^2));
%             end
        end
        
        function rotationVector = mat2rotVec(~, tranform_matrix)
            temp = rotm2axang(tranform_matrix(1:3, 1:3));
            rotationVector = temp(4) * temp(1:3);
            
%             R = tranform_matrix(1:3, 1:3);
%             angle = acos((R(1,1) + R(2,2) + R(3,3) - 1)/2);
%             if abs(angle) < eps
%                 rotationVector = zeros(1,3);
%             elseif abs(angle - pi) < eps
%                 if (R - rotx(180)) < eps
%                     rotationVector = [pi 0 0];
%                 elseif (R - roty(180)) < eps
%                     rotationVector = [0 pi 0];
%                 elseif (R - rotz(180)) < eps
%                     rotationVector = [0 0 pi];
%                 end
%             else
%                 kx = R(3,2) - R(2,3);
%                 ky = R(1,3) - R(3,1);
%                 kz = R(2,1) - R(1,2);
%                 rotationVector = [kx ky kz];
%                 rotationVector = angle*rotationVector/sqrt(sum(rotationVector.^2));
%             end
        end
        
        function unitQuaternion = mat2unitQuat(~, tranform_matrix)
           unitQuaternion = rotm2quat(tranform_matrix(1:3, 1:3));
            
%             R = tranform_matrix(1:3, 1:3);
%             angle = acos((R(1,1) + R(2,2) + R(3,3) - 1)/2);
%             if abs(angle) < eps
%                 unitQuaternion = [1 0 0 0];
%             elseif abs(angle - pi) < eps
%                 if (R - rotx(180)) < eps
%                     unitQuaternion = [0 1 0 0];
%                 elseif (R - roty(180)) < eps
%                     unitQuaternion = [0 0 1 0];
%                 elseif (R - rotz(180)) < eps
%                     unitQuaternion = [0 0 0 1];
%                 end
%             else
%                 kx = R(3,2) - R(2,3);
%                 ky = R(1,3) - R(3,1);
%                 kz = R(2,1) - R(1,2);
%                 q0 = cos(angle/2);
%                 coeff = sqrt(kx^2 + ky^2 + kz^2);
%                 q1 = kx * sin(angle/2) / coeff;
%                 q2 = ky * sin(angle/2) / coeff;
%                 q3 = kz * sin(angle/2) / coeff;
%                 unitQuaternion = [q0 q1 q2 q3];
%             end
        end
        
        function rotationMatrix = eulZYX2mat(~, eulerAngles, unit)
            if strcmp(unit, 'rad')
                eulerAngles = rad2deg(eulerAngles);
            end
            
            rotationMatrix = rotz(eulerAngles(1)) * roty(eulerAngles(2)) * rotx(eulerAngles(3));
        end
        
        function rotationMatrix = rotVec2mat(app, rotationVector)
            angle = sqrt(sum(rotationVector.^2));
            kx = rotationVector(1)/angle;
            ky = rotationVector(2)/angle;
            kz = rotationVector(3)/angle;
            rotationMatrix = axAng2mat(app, [angle, kx, ky, kz], 'rad');
        end
        
        function rotationMatrix = axAng2mat(~, axisAngles, angleUnit)
            if strcmp(angleUnit, 'deg')
                axisAngles(1) = deg2rad(axisAngles(1));
            end
            
            angle = axisAngles(1);
            if abs(angle) < eps
                rotationMatrix = eye(3);
                return;
            end
            % check axis input with [0 0 0]
            if isequal(abs(axisAngles(2:4)) < eps, [1 1 1])
                rotationMatrix = eye(3);
                return;
            end
            coeff = sqrt(sum(axisAngles(2:4).^2));
            kx = axisAngles(2)/coeff;
            ky = axisAngles(3)/coeff;
            kz = axisAngles(4)/coeff;
            Vers = 1 - cos(angle);
            
            rotationMatrix = [kx^2*Vers+cos(angle),     ky*kx*Vers-kz*sin(angle),   kz*kx*Vers+ky*sin(angle);
                kx*ky*Vers+kz*sin(angle), ky^2*Vers+cos(angle),       kz*ky*Vers-kx*sin(angle);
                kx*kz*Vers-ky*sin(angle), ky*kz*Vers+kx*sin(angle),   kz^2*Vers+cos(angle)];
        end
        
        function rotationMatrix = quat2mat(~, quaternion)
            quaternion = quaternion/norm(quaternion);
            s = quaternion(1);
            v = quaternion(2:4);
            v_mat = [0 -v(3) v(2); v(3) 0 -v(1); -v(2) v(1) 0];
            rotationMatrix = v'*v + s^2*eye(3) + 2*s*v_mat + v_mat^2;
        end
        
        function updateInterfaceValue(app)
            setRotationMatrix(app, getRotationMatrix(app));
            setEulerAnglesZYX(app, getEulerAnglesZYX(app));
            setRotationVector(app, getRotationVector(app));
            setAxisAngle(app, getAxisAngle(app));
            setUnitQuaternion(app, getUnitQuaternion(app));
        end
        
        function updateMotionData(app) % TODO：This function has a bug and is not currently available
            
            totalMotionDataPoints = 3*1+1*1+3*2+6*4;
            motionDataPoints = [];
            for i = 1:13
                if i < 4
                    % Extract data from Text
                    motionDataPoints(:,i) = app.UIAxes.Children(i).Position';
                elseif i < 8
                    % Extract data from Scatter、Line
                    temp = [app.UIAxes.Children(i).XData; app.UIAxes.Children(i).YData; app.UIAxes.Children(i).ZData];
                    motionDataPoints = [motionDataPoints, temp];
                else
                    % Extract data from Patch
                    temp = [app.UIAxes.Children(i).XData, app.UIAxes.Children(i).YData, app.UIAxes.Children(i).ZData];
                    motionDataPoints = [motionDataPoints, temp'];
                end
                
            end
            newPos = app.pose(1:3, 1:3) * motionDataPoints;
            
            j = 1;
            for i = 1:13
                if i < 4
                    % Text: j = 1:3
                    app.UIAxes.Children(i).Position = newPos(:, j)';
                    j = j + 1;
                elseif i < 5
                    % Scatter: j = 4
                    app.UIAxes.Children(i).XData = newPos(1, j);
                    app.UIAxes.Children(4).YData = newPos(2, j);
                    app.UIAxes.Children(4).ZData = newPos(3, j);
                    j = j + 1;
                elseif i < 8
                    % Line: j = 5:10
                    app.UIAxes.Children(i).XData = newPos(1, j:j+1);
                    app.UIAxes.Children(i).YData = newPos(2, j:j+1);
                    app.UIAxes.Children(i).ZData = newPos(3, j:j+1);
                    j = j + 2;
                else
                    % Patch: j = 11:34
                    app.UIAxes.Children(i).XData = newPos(1, j:j+3);
                    app.UIAxes.Children(i).YData = newPos(2, j:j+3);
                    app.UIAxes.Children(i).ZData = newPos(3, j:j+3);
                end
            end
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            axesInit(app);
            poseDataInit(app);
            drawObject(app, app.pose);
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            switchUnitSystem(app, strcmp(value, "(rad)"));
        end

        % Button pushed function: ApplyButton_matrix
        function ApplyButton_matrixPushed(app, event)
            R = eye(3);
            R(1,1) = app.EditField_nx.Value;
            R(2,1) = app.EditField_ny.Value;
            R(3,1) = app.EditField_nz.Value;
            R(1,2) = app.EditField_ox.Value;
            R(2,2) = app.EditField_oy.Value;
            R(3,2) = app.EditField_oz.Value;
            R(1,3) = app.EditField_ax.Value;
            R(2,3) = app.EditField_ay.Value;
            R(3,3) = app.EditField_az.Value;
            if checkInputValidity(app, 'rotation_matrix', R)
                app.pose(1:3, 1:3) = R;
                updateInterfaceValue(app);
                poseRun(app, 0);
            end
        end

        % Button pushed function: Button_convert_to_matrix
        function Button_convert_to_matrixPushed(app, event)
            matrix_order = [string(app.step1DropDown.Value), string(app.step2DropDown.Value), string(app.step3DropDown.Value)];
            angle_order = [app.EditField_step1.Value, app.EditField_step2.Value, app.EditField_step3.Value];
            if app.isRad == true
                angle_order = rad2deg(angle_order);
            end
            R = eye(3);
            j = 1;
            for i = matrix_order
                switch i
                    case "fixed-axis X"
                        R = rotx(angle_order(j)) * R;
                    case "fixed-axis Y"
                        R = roty(angle_order(j)) * R;
                    case "fixed-axis Z"
                        R = rotz(angle_order(j)) * R;
                    case "float-axis X"
                        R = R * rotx(angle_order(j));
                    case "float-axis Y"
                        R = R * roty(angle_order(j));
                    case "float-axis Z"
                        R = R * rotz(angle_order(j));
                end
                j = j + 1;
            end
            setRotationMatrix(app, R);
        end

        % Button pushed function: ApplyButton_euler
        function ApplyButton_eulerPushed(app, event)
            euler_zyx = [app.yawEditField.Value, app.pitchEditField.Value, app.rollEditField.Value];
            if checkInputValidity(app, 'euler_angles', euler_zyx)
                if app.isRad
                    app.pose(1:3, 1:3) = eulZYX2mat(app, euler_zyx, 'rad');
                else
                    app.pose(1:3, 1:3) = eulZYX2mat(app, euler_zyx, 'deg');
                end
                updateInterfaceValue(app);
                poseRun(app);
            end
        end

        % Button pushed function: ApplyButton_axis
        function ApplyButton_axisPushed(app, event)
            axis_angles = [app.AngleEditField.Value, app.kxEditField.Value, app.kyEditField.Value, app.kzEditField.Value];
            if checkInputValidity(app, 'axis_angles', axis_angles)
                if app.isRad
                    app.pose(1:3, 1:3) = axAng2mat(app, axis_angles, 'rad');
                else
                    app.pose(1:3, 1:3) = axAng2mat(app, axis_angles, 'deg');
                end
                updateInterfaceValue(app);
                poseRun(app);
            end
        end

        % Button pushed function: ApplyButton_vector
        function ApplyButton_vectorPushed(app, event)
            rotation_vector = [app.RXEditField.Value, app.RYEditField.Value, app.RZEditField.Value];
            if checkInputValidity(app, 'rotation_vector', rotation_vector)
                app.pose(1:3, 1:3) = rotVec2mat(app, rotation_vector);
                updateInterfaceValue(app);
                poseRun(app);
            end
        end

        % Button pushed function: ApplyButton_quaternion
        function ApplyButton_quaternionPushed(app, event)
            quaternion = [app.q0EditField.Value, app.q1EditField.Value, app.q2EditField.Value, app.q3EditField.Value];
            if checkInputValidity(app, 'unit_quaternion', quaternion)
                app.pose(1:3, 1:3) = quat2mat(app, quaternion);
                updateInterfaceValue(app);
                poseRun(app);
            end
        end

        % Button pushed function: Button_addPose
        function Button_addPosePushed(app, event)
            % input item can not be empty
            if strcmp(app.EditField_inputPoseName.Value, '')
                uialert(app.UIFigure, 'The custom pose name cannot be empty！', 'Invalid Input', "Icon", "warning");
                return;
            end
            % The input item cannot conflict with an existing item
            for i = 1:numel(app.SelectPoseDropDown.Items)
                if strcmp(app.SelectPoseDropDown.Items{i}, app.EditField_inputPoseName.Value)
                    msg = sprintf("%s is already exist！", app.EditField_inputPoseName.Value);
                    uialert(app.UIFigure, msg, 'Invalid Input', "Icon", "warning");
                    return;
                end
            end
            app.SelectPoseDropDown.Items{end+1} = app.EditField_inputPoseName.Value;
            app.poseSet{end+1} = [getRotationMatrix(app) zeros(3,1); 0 0 0 1];
            app.SelectPoseDropDown.Value = app.SelectPoseDropDown.Items{end};
        end

        % Button pushed function: Button_delPose
        function Button_delPosePushed(app, event)
            if numel(app.SelectPoseDropDown.Items) == 0
                uialert(app.UIFigure, 'The custom pose list is empty and cannot be deleted！', 'Invalid Operation', "Icon", "warning");
                return;
            end
            idx = find(strcmp(app.SelectPoseDropDown.Value, app.SelectPoseDropDown.Items));
            app.SelectPoseDropDown.Items(idx) = [];
            app.poseSet(idx) = [];
        end

        % Button pushed function: ApplyButton_runSelectPose
        function ApplyButton_runSelectPosePushed(app, event)
            idx = find(strcmp(app.SelectPoseDropDown.Value, app.SelectPoseDropDown.Items));
            app.pose = app.poseSet{idx};
            updateInterfaceValue(app);
            poseRun(app);
        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            key
            pitchNext = app.pitchEditField.Value;
            yawNext = app.yawEditField.Value;
            rollNext = app.rollEditField.Value;
            if app.isRad
                flag = 'rad';
                gain = 0.05;
                pitchRange = [-pi/2 pi/2];
                yawRange = [-pi pi];
                rollRange = [-pi pi];
            else
                flag = 'deg';
                gain = 1;
                pitchRange = [-90 90];
                yawRange = [-180 180];
                rollRange = [-180 180];
            end
            switch key
                case 'uparrow'
                    pitchNext = pitchNext - gain;
                case 'downarrow'
                    pitchNext = pitchNext + gain;
                case 'leftarrow'
                    yawNext = yawNext + gain;
                case 'rightarrow'
                    yawNext = yawNext - gain;
                case 'comma'
                    rollNext = rollNext - gain;
                case 'period'
                    rollNext = rollNext + gain;
            end
            if pitchNext > pitchRange(2)
                pitchNext = pitchRange(2);
            end
            if pitchNext < pitchRange(1)
                pitchNext = pitchRange(1);
            end
            if yawNext > yawRange(2)
                yawNext = yawRange(2);
            end
            if yawNext < yawRange(1)
                yawNext = yawRange(1);
            end
            if rollNext > rollRange(2)
                rollNext = rollRange(2);
            end
            if rollNext < rollRange(1)
                rollNext = rollRange(1);
            end
            euler_next = [yawNext, pitchNext, rollNext];
            if checkInputValidity(app, 'euler_angles', euler_next)
                app.pose(1:3, 1:3) = eulZYX2mat(app, euler_next, flag);
                updateInterfaceValue(app);
                poseRun(app);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.RotationMatrixPanel = uipanel(app.GridLayout);
            app.RotationMatrixPanel.Title = 'Rotation Matrix';
            app.GridLayout2 = uigridlayout(app.RotationMatrixPanel);
            app.EditField_nx = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_nx.Value = 1;
            app.EditField_ox = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_ax = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_ny = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_oy = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_oy.Value = 1;
            app.EditField_ay = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_nz = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_oz = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_az = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_az.Value = 1;
            app.ApplyButton_matrix = uibutton(app.GridLayout2, 'push');
            app.ApplyButton_matrix.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_matrixPushed, true);
            app.ApplyButton_matrix.Text = 'Apply';
            app.step1DropDownLabel = uilabel(app.GridLayout2);
            app.step1DropDownLabel.Text = 'step 1';
            app.step1DropDown = uidropdown(app.GridLayout2);
            app.step1DropDown.Items = {'fixed-axis X', 'fixed-axis Y', 'fixed-axis Z', 'float-axis X', 'float-axis Y', 'float-axis Z'};
            app.step1DropDown.Value = 'float-axis X';
            app.EditField_step1 = uieditfield(app.GridLayout2, 'numeric');
            app.step2DropDownLabel = uilabel(app.GridLayout2);
            app.step2DropDownLabel.Text = 'step 2';
            app.step2DropDown = uidropdown(app.GridLayout2);
            app.step2DropDown.Items = {'fixed-axis X', 'fixed-axis Y', 'fixed-axis Z', 'float-axis X', 'float-axis Y', 'float-axis Z'};
            app.step2DropDown.Value = 'float-axis Y';
            app.step3DropDownLabel = uilabel(app.GridLayout2);
            app.step3DropDownLabel.Text = 'step 3';
            app.step3DropDown = uidropdown(app.GridLayout2);
            app.step3DropDown.Items = {'fixed-axis X', 'fixed-axis Y', 'fixed-axis Z', 'float-axis X', 'float-axis Y', 'float-axis Z'};
            app.step3DropDown.Value = 'float-axis Z';
            app.EditField_step2 = uieditfield(app.GridLayout2, 'numeric');
            app.EditField_step3 = uieditfield(app.GridLayout2, 'numeric');
            app.Button_convert_to_matrix = uibutton(app.GridLayout2, 'push');
            app.Button_convert_to_matrix.ButtonPushedFcn = createCallbackFcn(app, @Button_convert_to_matrixPushed, true);
            app.Button_convert_to_matrix.Text = '>>';
            app.Label_step1_unit = uilabel(app.GridLayout2);
            app.Label_step1_unit.Text = '°';
            app.Label_step2_unit = uilabel(app.GridLayout2);
            app.Label_step2_unit.Text = '°';
            app.Label_step3_unit = uilabel(app.GridLayout2);
            app.Label_step3_unit.Text = '°';
            app.EulerAnglesZYXPanel = uipanel(app.GridLayout);
            app.EulerAnglesZYXPanel.Title = 'Euler Angles (ZYX)';
            app.GridLayout3 = uigridlayout(app.EulerAnglesZYXPanel);
            app.yawLabel = uilabel(app.GridLayout3);
            app.yawLabel.Text = 'yaw:';
            app.yawEditField = uieditfield(app.GridLayout3, 'numeric');
            app.pitchLabel = uilabel(app.GridLayout3);
            app.pitchLabel.Text = 'pitch:';
            app.pitchEditField = uieditfield(app.GridLayout3, 'numeric');
            app.rollLabel = uilabel(app.GridLayout3);
            app.rollLabel.Text = 'roll:';
            app.rollEditField = uieditfield(app.GridLayout3, 'numeric');
            app.ApplyButton_euler = uibutton(app.GridLayout3, 'push');
            app.ApplyButton_euler.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_eulerPushed, true);
            app.ApplyButton_euler.Text = 'Apply';
            app.Label_yaw_unit = uilabel(app.GridLayout3);
            app.Label_yaw_unit.Text = '°';
            app.Label_pitch_unit = uilabel(app.GridLayout3);
            app.Label_pitch_unit.Text = '°';
            app.Label_roll_unit = uilabel(app.GridLayout3);
            app.Label_roll_unit.Text = '°';
            app.RotationVectorPanel = uipanel(app.GridLayout);
            app.RotationVectorPanel.Title = 'Rotation Vector';
            app.GridLayout3_2 = uigridlayout(app.RotationVectorPanel);
            app.RXEditFieldLabel = uilabel(app.GridLayout3_2);
            app.RXEditFieldLabel.Text = 'RX:';
            app.RXEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RYEditFieldLabel = uilabel(app.GridLayout3_2);
            app.RYEditFieldLabel.Text = 'RY:';
            app.RYEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.RZEditFieldLabel = uilabel(app.GridLayout3_2);
            app.RZEditFieldLabel.Text = 'RZ:';
            app.RZEditField = uieditfield(app.GridLayout3_2, 'numeric');
            app.ApplyButton_vector = uibutton(app.GridLayout3_2, 'push');
            app.ApplyButton_vector.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_vectorPushed, true);
            app.ApplyButton_vector.Text = 'Apply';
            app.UnitQuaternionPanel = uipanel(app.GridLayout);
            app.UnitQuaternionPanel.Title = 'Unit Quaternion';
            app.GridLayout4_2 = uigridlayout(app.UnitQuaternionPanel);
            app.q0EditFieldLabel = uilabel(app.GridLayout4_2);
            app.q0EditFieldLabel.Text = 'q0:';
            app.q0EditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.q1EditFieldLabel = uilabel(app.GridLayout4_2);
            app.q1EditFieldLabel.Text = 'q1:';
            app.q1EditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.q2EditFieldLabel = uilabel(app.GridLayout4_2);
            app.q2EditFieldLabel.Text = 'q2:';
            app.q2EditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.q3EditFieldLabel = uilabel(app.GridLayout4_2);
            app.q3EditFieldLabel.Text = 'q3:';
            app.q3EditField = uieditfield(app.GridLayout4_2, 'numeric');
            app.ApplyButton_quaternion = uibutton(app.GridLayout4_2, 'push');
            app.ApplyButton_quaternion.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_quaternionPushed, true);
            app.ApplyButton_quaternion.Text = 'Apply';
            app.AxisAnglePanel = uipanel(app.GridLayout);
            app.AxisAnglePanel.Title = 'Axis-Angle';
            app.GridLayout4 = uigridlayout(app.AxisAnglePanel);
            app.AngleLabel = uilabel(app.GridLayout4);
            app.AngleLabel.Text = 'Angle:';
            app.AngleEditField = uieditfield(app.GridLayout4, 'numeric');
            app.kxEditFieldLabel = uilabel(app.GridLayout4);
            app.kxEditFieldLabel.Text = 'kx:';
            app.kxEditField = uieditfield(app.GridLayout4, 'numeric');
            app.kyEditFieldLabel = uilabel(app.GridLayout4);
            app.kyEditFieldLabel.Text = 'ky:';
            app.kyEditField = uieditfield(app.GridLayout4, 'numeric');
            app.kzEditFieldLabel = uilabel(app.GridLayout4);
            app.kzEditFieldLabel.Text = 'kz:';
            app.kzEditField = uieditfield(app.GridLayout4, 'numeric');
            app.ApplyButton_axis = uibutton(app.GridLayout4, 'push');
            app.ApplyButton_axis.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_axisPushed, true);
            app.ApplyButton_axis.Text = 'Apply';
            app.Label_axis_angle_unit = uilabel(app.GridLayout4);
            app.Label_axis_angle_unit.Text = '°';
            app.Switch = uiswitch(app.GridLayout, 'slider');
            app.Switch.Items = {'(°)', '(rad)'};
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.Switch.Value = '(°)';
            app.Panel = uipanel(app.GridLayout);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.SelectPoseLabel = uilabel(app.GridLayout5);
            app.SelectPoseLabel.Text = 'Select Pose:';
            app.SelectPoseDropDown = uidropdown(app.GridLayout5);
            app.SelectPoseDropDown.Items = {'Pose0'};
            app.SelectPoseDropDown.Value = 'Pose0';
            app.Button_addPose = uibutton(app.GridLayout5, 'push');
            app.Button_addPose.ButtonPushedFcn = createCallbackFcn(app, @Button_addPosePushed, true);
            app.Button_addPose.Icon = 'add.svg';
            app.Button_addPose.Tooltip = {'Add Pose'};
            app.Button_addPose.Text = '';
            app.Button_delPose = uibutton(app.GridLayout5, 'push');
            app.Button_delPose.ButtonPushedFcn = createCallbackFcn(app, @Button_delPosePushed, true);
            app.Button_delPose.Icon = 'del.svg';
            app.Button_delPose.Tooltip = {'Delete Pose'};
            app.Button_delPose.Text = '';
            app.ApplyButton_runSelectPose = uibutton(app.GridLayout5, 'push');
            app.ApplyButton_runSelectPose.ButtonPushedFcn = createCallbackFcn(app, @ApplyButton_runSelectPosePushed, true);
            app.ApplyButton_runSelectPose.Text = 'Apply';
            app.EditField_inputPoseName = uieditfield(app.GridLayout5, 'text');
            app.EditField_inputPoseName.Tooltip = {'type custom pose name'};
            app.UIAxes = uiaxes(app.GridLayout);
            app.UIAxes.YTick = [];
            app.UIAxes.ZTick = [];
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PoseVisualization

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end